classdef CleaningV < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EditButton                   matlab.ui.control.Button
        RadarSelectionDropDownLabel  matlab.ui.control.Label
        RadarSelectionDropDown       matlab.ui.control.DropDown
        previous                     matlab.ui.control.Button
        next                         matlab.ui.control.Button
        SavetofileButton             matlab.ui.control.Button
        Figure2DropDownLabel         matlab.ui.control.Label
        DropDownFig2                 matlab.ui.control.DropDown
        Figure1editDropDownLabel     matlab.ui.control.Label
        DropDownFig1                 matlab.ui.control.DropDown
        LogScaleCheckBoxFig1         matlab.ui.control.CheckBox
        LogScaleCheckBoxFig2         matlab.ui.control.CheckBox
        AxesFig1                     matlab.ui.control.UIAxes
        AxesFig2                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        d;
        dt;
        i_d=1;
        fig=1;
        button;
        fig1='dens';
        fig2='DBZH';
        logScaleFig1=false;
        logScaleFig2=false;
    end

    methods (Access = private)
        
        function rect(app,val)
            set(app.button.nan,'Enable','off'); set(app.button.zero,'Enable','off'); set(app.button.fill,'Enable','off'); 
            set(app.button.fill0,'Enable','off'); set(app.button.fillnan,'Enable','off'); set(app.button.copyline,'Enable','off');set(app.button.refresh,'Enable','off');
            figure(app.fig); subplot(2,1,1);
            rect = getrect(gca);
            x = datenum(app.d(app.i_d).time);
            y = app.d(app.i_d).interval*(1/2:double(app.d(app.i_d).levels));
            idx = x>=rect(1) & x<=rect(1)+rect(3);
            idy = y>=rect(2) & y<=rect(2)+rect(4);
            [X,Y]=meshgrid(x(idx),y(idy));
            figure(app.fig); hold on; scatter(X(:),Y(:),'r.')
            switch val
                case 'nan'
                    app.dt(idx,idy)=NaN;
                case'zero'
                    app.dt(idx,idy)=0;
                case 'fill'
                    V = app.dt;
                    id_nan =isnan(V);
                    V(idx,idy)=nan;
                    V=fillmissing(V,'pchip');
                    V(V<0)=0;
                    V(id_nan)=NaN;
                    app.dt=V;
                case 'fill0'
                    V = app.dt;
                    id_nan =isnan(V);
                    a=V(idx,idy);
                    a(a==0)=nan; 
                    V(idx,idy)=a;
                    V=fillmissing(V,'pchip');
                    V(V<0)=0;
                    V(id_nan)=NaN;
                    app.dt=V;
                case 'fillnan'
                    V = app.dt;
                    V(isnan(V)) = -1000;
                    a=V(idx,idy);
                    a(a<-999)=nan; 
                    V(idx,idy)=a;
                    V=fillmissing(V,'pchip');
                    V(V<-999)=NaN;
                    V(V<0)=0;
                    app.dt=V;
                case 'copyline'
                    id_copy = find(idy, 1 );
                    for id_y=1:id_copy-1
                        app.dt(idx,id_y) = app.dt(idx,id_copy);
                    end
                case 'multiply'
                    answer = str2double(inputdlg('Value to multiply by'));
                    app.dt(idx,idy) = answer.*app.dt(idx,idy);
            end
            refreshFig(app)
        end
        
        function refreshFig(app)
            figure(app.fig); a_x = axis; clf;
            ax1=subplot(2,1,1); hold on;
            if app.logScaleFig1
                var = log10(app.dt'+1);
            else
                var = app.dt';
            end
            h=surf(datenum(app.d(app.i_d).time), app.d(app.i_d).interval*(1/2:double(app.d(app.i_d).levels)), var,'AlphaData',~isnan(var),'EdgeColor','none');
            set(h,'ZData',get(h,'ZData')-10)
            plot([datenum(min(app.d(app.i_d).time)) datenum(max(app.d(app.i_d).time))],[app.d(app.i_d).height app.d(app.i_d).height],'k','linewidth',2); view(2)
            if isfield(app.d,'sunrise')
                plot(repmat(datenum(unique(app.d(app.i_d).sunrise))',2,1),[0 5000],'-k'); 
                plot(repmat(datenum(unique(app.d(app.i_d).sunset))',2,1),[0 5000],'-k');
            end
            datetick('x');set(gca,'XTick',round(datenum(app.d(app.i_d).time(1):app.d(app.i_d).time(end))))
            set(gca,'XTickLabel',datestr(round(datenum(app.d(app.i_d).time(1):app.d(app.i_d).time(end))),'dd-mm'))
            
            ax2=subplot(2,1,2); hold on;
            if app.logScaleFig2
                var = log10(app.d(app.i_d).(app.fig2)');
            else
                var = app.d(app.i_d).(app.fig2)';
            end
            h=surf(datenum(app.d(app.i_d).time), app.d(app.i_d).interval*(1/2:double(app.d(app.i_d).levels)), var,'AlphaData',~isnan(var),'EdgeColor','none');
            set(h,'ZData',get(h,'ZData')-100);
            if isfield(app.d,'tcrw')
                plot(datenum(app.d(app.i_d).time), app.d(app.i_d).tcrw*5000/max( app.d(app.i_d).tcrw),'r-','linewidth',2); 
                plot(datenum([app.d(app.i_d).time(1) app.d(app.i_d).time(end)]),[0.05 0.05]*5000/max( app.d(app.i_d).tcrw),'--r'); 
                text(datenum(app.d(app.i_d).time(1)),0.05*5000/max( app.d(app.i_d).tcrw),'threshold at 0.05')
                plot(datenum([app.d(app.i_d).time(1) app.d(app.i_d).time(end)]),[0.03 0.03]*5000/max( app.d(app.i_d).tcrw),'--r');
                text(datenum(app.d(app.i_d).time(1)),0.03*5000/max( app.d(app.i_d).tcrw),'threshold at 0.03')
            end
%             if isfield(app.d,'sunrise')
%                 sr = unique(app.d(app.i_d).sunrise);
%                 ss = unique(app.d(app.i_d).sunset);
%                 plot(repmat(sr,1,2), repmat([0 5000],numel(sr),1) );
%                 plot(repmat(ss,1,2), repmat([0 5000],numel(ss),1) );
%             end
            view(2)
            datetick('x');set(gca,'XTick',round(datenum(app.d(app.i_d).time(1):app.d(app.i_d).time(end))))
            set(gca,'XTickLabel',datestr(round(datenum(app.d(app.i_d).time(1):app.d(app.i_d).time(end))),'dd-mm'))
            
            linkaxes([ax1,ax2],'xy')
            if ~all(a_x==[0 1 0 1]); axis(a_x);else, axis tight; end
                        
            app.button.nan=uicontrol('Parent',app.fig,'Style','pushbutton','String','Fill with NaN','Units','normalized','Position',[0.01 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) rect(app,'nan'));
            app.button.zero=uicontrol('Parent',app.fig,'Style','pushbutton','String','Fill with 0','Units','normalized','Position',[0.08 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) rect(app,'zero'));
            app.button.fill=uicontrol('Parent',app.fig,'Style','pushbutton','String','Interpolation','Units','normalized','Position',[0.15 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) rect(app,'fill'));
            app.button.fill0=uicontrol('Parent',app.fig,'Style','pushbutton','String','Interpolation only 0','Units','normalized','Position',[0.22 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) rect(app,'fill0'));
            app.button.fillnan=uicontrol('Parent',app.fig,'Style','pushbutton','String','Interpolation only NaN','Units','normalized','Position',[0.29 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) rect(app,'fillnan'));
            app.button.copyline=uicontrol('Parent',app.fig,'Style','pushbutton','String','Copy line below','Units','normalized','Position',[0.36 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) rect(app,'copyline'));
            app.button.copyline=uicontrol('Parent',app.fig,'Style','pushbutton','String','Multiply value','Units','normalized','Position',[0.43 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) rect(app,'multiply'));
            app.button.refresh=uicontrol('Parent',app.fig,'Style','pushbutton','String','Save changes','Units','normalized','Position',[0.92 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) saveFig(app));
        end
        
        function saveFig(app)
            app.d(app.i_d).(app.fig1)=app.dt;
            refreshAxes(app)
        end
        
        function refreshAxes(app)
            app.RadarSelectionDropDown.Value = app.d(app.i_d).name;
            
            cla(app.AxesFig1); hold(app.AxesFig1,'on')
            if app.logScaleFig1
                var = log10(app.d(app.i_d).(app.fig1)');
            else
                var = app.d(app.i_d).(app.fig1)';
            end
            imagesc(app.AxesFig1, datenum(app.d(app.i_d).time), app.d(app.i_d).interval*(1/2:double(app.d(app.i_d).levels)), var, 'AlphaData',~isnan(var))
            plot(app.AxesFig1,[datenum(min(app.d(app.i_d).time)) datenum(max(app.d(app.i_d).time))],[app.d(app.i_d).height app.d(app.i_d).height],'k','linewidth',2); 
            xlabel(app.AxesFig1,'date'); ylabel(app.AxesFig1,'[m]'); title(app.AxesFig1,'Density [bird/m^3]'); colorbar(app.AxesFig1);
            datetick(app.AxesFig1,'x');  set(app.AxesFig1, 'YDir', 'normal');
            set(app.AxesFig1,'XTick',datenum(app.d(app.i_d).time(1):10:app.d(app.i_d).time(end)))
            set(app.AxesFig1,'XTickLabel',datestr(app.d(app.i_d).time(1):10:app.d(app.i_d).time(end),'dd-mm'))
            axis(app.AxesFig1,[datenum(min(app.d(app.i_d).time)) datenum(max(app.d(app.i_d).time)) 0 5000]); % caxis(app.AxesFig1,[-5 5])
            

            cla(app.AxesFig2); hold(app.AxesFig2,'on')
            if app.logScaleFig2
                var = log10(app.d(app.i_d).(app.fig2)');
            else
                var = app.d(app.i_d).(app.fig2)';
            end
            imagesc(app.AxesFig2,datenum(app.d(app.i_d).time), app.d(app.i_d).interval*(1/2:double(app.d(app.i_d).levels)),var,'AlphaData',~isnan(var))
            if isfield(app.d,'tcrw')
                plot(app.AxesFig2,datenum(app.d(app.i_d).time), app.d(app.i_d).tcrw*5000/max( app.d(app.i_d).tcrw),'r-')
                plot(app.AxesFig2,datenum([app.d(app.i_d).time(1) app.d(app.i_d).time(end)]),[0.05 0.05]*5000/max( app.d(app.i_d).tcrw),'--r'); 
                text(app.AxesFig2,datenum(app.d(app.i_d).time(1)),0.05*5000/max( app.d(app.i_d).tcrw),'threshold at 0.05')
                plot(app.AxesFig2,datenum([app.d(app.i_d).time(1) app.d(app.i_d).time(end)]),[0.03 0.03]*5000/max( app.d(app.i_d).tcrw),'--r');
                text(app.AxesFig2,datenum(app.d(app.i_d).time(1)),0.03*5000/max( app.d(app.i_d).tcrw),'threshold at 0.03')
            end
            
            % plot(app.AxesFig2,datenum(app.d(app.i_d).time([1 end])),[1 1].*0.005*5000/max( app.d(app.i_d).tcrw),'--r')
            % imagesc(app.AxesFig2,datenum(app.d(app.i_d).time), app.d(app.i_d).interval*(1/2:app.d(app.i_d).maxrange), -50*ones(size(app.d(app.i_d).DBZH')),'AlphaData', 0.6*repmat(app.d(app.i_d).tcrw'>0.005,size(app.d(app.i_d).DBZH,2),1))
            % xlabel(app.AxesFig2,'date'); ylabel(app.AxesFig2,'[m]'); title(app.AxesFig2,'Reflectivity []'); colorbar(app.AxesFig2);
            datetick(app.AxesFig2,'x');  set(app.AxesFig2, 'YDir', 'normal')
            set(app.AxesFig2,'XTick',datenum(app.d(app.i_d).time(1):10:app.d(app.i_d).time(end)))
            set(app.AxesFig2,'XTickLabel',datestr(app.d(app.i_d).time(1):10:app.d(app.i_d).time(end),'dd-mm'))
            axis(app.AxesFig2,[datenum(min(app.d(app.i_d).time)) datenum(max(app.d(app.i_d).time)) 0 5000]);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dc)
            app.d = dc;
            app.RadarSelectionDropDown.Items={app.d.name};
            refreshAxes(app);
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            app.dt=app.d(app.i_d).(app.fig1);
            refreshFig(app) 
        end

        % Value changed function: RadarSelectionDropDown
        function RadarSelectionDropDownValueChanged(app, event)
            app.i_d=find(strcmp(app.RadarSelectionDropDown.Value,{app.d.name}));
            refreshAxes(app)
        end

        % Button pushed function: previous
        function previousButtonPushed(app, event)
            app.i_d=max(app.i_d-1,1);
            refreshAxes(app)
        end

        % Button pushed function: next
        function nextButtonPushed(app, event)
            app.i_d=min(app.i_d+1,numel(app.d));
            refreshAxes(app)
        end

        % Button pushed function: SavetofileButton
        function SavetofileButtonPushed(app, event)
            [file,path] = uiputfile(['data/dc_' datestr(now,'YYYYmmdd_HHMM') '.mat'],'Save file name');
            dc=app.d;
            save([path file],'dc');
        end

        % Value changed function: DropDownFig2
        function DropDownFig2ValueChanged(app, event)
            app.fig2 = app.DropDownFig2.Value;
            refreshAxes(app)
        end

        % Value changed function: DropDownFig1
        function DropDownFig1ValueChanged(app, event)
            app.fig1 = app.DropDownFig1.Value;
            refreshAxes(app)
        end

        % Value changed function: LogScaleCheckBoxFig1
        function LogScaleCheckBoxFig1ValueChanged(app, event)
            app.logScaleFig1 = app.LogScaleCheckBoxFig1.Value;
            refreshAxes(app)
        end

        % Value changed function: LogScaleCheckBoxFig2
        function LogScaleCheckBoxFig2ValueChanged(app, event)
            app.logScaleFig2 = app.LogScaleCheckBoxFig2.Value;
            refreshAxes(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EditButton = uibutton(app.UIFigure, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.RadarSelectionDropDownLabel = uilabel(app.UIFigure);
            app.RadarSelectionDropDownLabel.Text = 'Radar Selection:';
            app.RadarSelectionDropDown = uidropdown(app.UIFigure);
            app.RadarSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @RadarSelectionDropDownValueChanged, true);
            app.previous = uibutton(app.UIFigure, 'push');
            app.previous.ButtonPushedFcn = createCallbackFcn(app, @previousButtonPushed, true);
            app.previous.Text = '<';
            app.next = uibutton(app.UIFigure, 'push');
            app.next.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.next.Text = '>';
            app.SavetofileButton = uibutton(app.UIFigure, 'push');
            app.SavetofileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetofileButtonPushed, true);
            app.SavetofileButton.Text = 'Save to file';
            app.Figure2DropDownLabel = uilabel(app.UIFigure);
            app.Figure2DropDownLabel.Text = 'Figure 2';
            app.DropDownFig2 = uidropdown(app.UIFigure);
            app.DropDownFig2.Items = {'DBZH', 'dens', 'ff', 'dd'};
            app.DropDownFig2.ValueChangedFcn = createCallbackFcn(app, @DropDownFig2ValueChanged, true);
            app.DropDownFig2.Value = 'DBZH';
            app.Figure1editDropDownLabel = uilabel(app.UIFigure);
            app.Figure1editDropDownLabel.Text = 'Figure 1 (edit)';
            app.DropDownFig1 = uidropdown(app.UIFigure);
            app.DropDownFig1.Items = {'dens', 'DBZH', 'ff', 'dd'};
            app.DropDownFig1.ValueChangedFcn = createCallbackFcn(app, @DropDownFig1ValueChanged, true);
            app.DropDownFig1.Value = 'dens';
            app.LogScaleCheckBoxFig1 = uicheckbox(app.UIFigure);
            app.LogScaleCheckBoxFig1.ValueChangedFcn = createCallbackFcn(app, @LogScaleCheckBoxFig1ValueChanged, true);
            app.LogScaleCheckBoxFig1.Text = 'Log Scale Figure 1';
            app.LogScaleCheckBoxFig2 = uicheckbox(app.UIFigure);
            app.LogScaleCheckBoxFig2.ValueChangedFcn = createCallbackFcn(app, @LogScaleCheckBoxFig2ValueChanged, true);
            app.LogScaleCheckBoxFig2.Text = 'Log Scale Figure 2';
            app.AxesFig1 = uiaxes(app.UIFigure);
            ylabel(app.AxesFig1, 'Altitude [m]')
            app.AxesFig2 = uiaxes(app.UIFigure);
            xlabel(app.AxesFig2, 'Date')
            ylabel(app.AxesFig2, 'Altitude [m]')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CleaningV(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end